classdef facedetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        Panel            matlab.ui.container.Panel
        LookingforLabel  matlab.ui.control.Label
        Panel_2          matlab.ui.container.Panel
        Label            matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %This script runs through train/test splitting the images, 
            %computes the class models, then performing predictions for 
            % each test image.
            
            fpath = uigetdir;     %set folder path
            train_ratio = 0.5     %set train ratio
            
            
            [train_models, test_ims, test_labels, class_rep] = getTrainTest( ...
                fpath, train_ratio);  %get training models, test images, 
            % and test labels
            
            fnames = fieldnames(train_models); 
            
            %get field names of train_model structure
            %loop through each test image in test_ims
            % then compute the distance between test image and each 
            % training class model
            % , take the min distance field name as our predicted class, 
            % display predicted image.
            for i = 1:length(test_ims)
               y = getClassModel(test_ims(i));
               imshow(test_ims{i}, "Parent", app.UIAxes)
               
               for j = 1:numel(fnames)
                   % 
                    X = train_models.(string(fnames(j)));
                    y_hat = X*inv((transpose(X)*X))*transpose(X)*y;
                    distance(j) = sqrt(sum((y(:) - y_hat(:)) .^ 2));
        
               end
               
               app.Label.Text = "";
               pause(1/2)
    
               app.Label.Text = "FOUND!!";
               pred = fnames(find(distance == min(distance)));
               imshow(class_rep.(string(pred{1})), "Parent", app.UIAxes2)
               pause(5)
               
               cla(app.UIAxes2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.LookingforLabel = uilabel(app.Panel);
            app.LookingforLabel.Text = 'Looking for ...';
            app.Panel_2 = uipanel(app.GridLayout);
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, ' ')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            zlabel(app.UIAxes2, '')
            app.UIAxes2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = facedetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end